classdef Signal_box_v3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        NoiseS_dBLabel             matlab.ui.control.Label
        Noise                      matlab.ui.control.DiscreteKnob
        Label                      matlab.ui.control.Label
        PeriodKnobLabel            matlab.ui.control.Label
        Period                     matlab.ui.control.Knob
        DominSwitchLabel           matlab.ui.control.Label
        Domin                      matlab.ui.control.Switch
        LampLabel                  matlab.ui.control.Label
        Lamp                       matlab.ui.control.Lamp
        Panel                      matlab.ui.container.Panel
        add                        matlab.ui.control.Button
        minus                      matlab.ui.control.Button
        AmpEditFieldLabel          matlab.ui.control.Label
        Amp                        matlab.ui.control.NumericEditField
        SignalSelectDropDownLabel  matlab.ui.control.Label
        SignalSelect               matlab.ui.control.DropDown
        Modulation                 matlab.ui.control.StateButton
        Mul                        matlab.ui.control.StateButton
        UITable                    matlab.ui.control.Table
        AnalyzeLabel               matlab.ui.control.Label
        Analyze                    matlab.ui.control.DiscreteKnob
        pass_upSliderLabel         matlab.ui.control.Label
        pass_up                    matlab.ui.control.Slider
        stop_upSliderLabel         matlab.ui.control.Label
        stop_up                    matlab.ui.control.Slider
        stop_downSliderLabel       matlab.ui.control.Label
        stop_down                  matlab.ui.control.Slider
        pass_downSliderLabel       matlab.ui.control.Label
        pass_down                  matlab.ui.control.Slider
        FilterLabel                matlab.ui.control.Label
        Filter                     matlab.ui.control.DiscreteKnob
        TabGroup                   matlab.ui.container.TabGroup
        SignalDisplayTab           matlab.ui.container.Tab
        UIAxes                     matlab.ui.control.UIAxes
        FilterDisplayTab           matlab.ui.container.Tab
    end


    properties (Access = private)
        autoupdate % Description
        F % Description
        xx % Description
        tt % Description
        multix % Description
    end

    methods (Access = private)
        function updatePlot(app)
            results0 = generateSignal(app);
            results1 = depositSignal(app,results0(1,:),results0(2,:));
            plot(app.UIAxes, results1(1,:), results1(2,:));
        end
        
        function results = generateSignal(app)
%           Get value
            signal = app.SignalSelect.Value;
            amp = app.Amp.Value;
            f = app.F;
            noise = str2double(app.Noise.Value);
            
            fs = 1000;
            t = 0:1/fs:1;
%           Create chosen signal
            switch signal
                case 'None'
                    x = ones(1,length(t));
                case 'Square'
                    x = square(2*pi*f*t,50);
                case 'Triangular'
                    x = sawtooth(2*pi*f*t,0.5);
                case 'Sine'
                    x = sin(2*pi*f*t);
                otherwise
                    x = zeros(length(t));
            end
            
            x = x * amp;
            x = awgn(x,noise,'measured');
            
            results = [t; x];
        end   
    
        function results = depositSignal(app,ti,xi)

            if(app.Mul.Value)
                app.multix = xi;
            else
                app.tt = ti;
                app.xx = xi;
            end
            t = app.tt;x = app.xx;
            
            filter = app.Filter.Value;
            domin = app.Domin;

%           Modulation
            if(app.Modulation.Value)
                x = x.*cos(2*pi*100*t);
            end
            
%           Signal Analyze
            if(strcmp(app.Analyze.Value,'Power'))
                temp = powerAnalyze(app,t,x);
                t = temp(1,:); x = temp(2,:);
            end
            
%           Time/Frequency Domin exchange
            if(strcmp(domin.Value,'Freq'))
                results = time2freq(app,t,x);
                app.Lamp.Color = [0 1 0];
            else
                results = [t;x];
                app.Lamp.Color = [0.5 0.5 0.5];
            end
        end
        
%       Convert time signal to frequency signal
        function results = time2freq(~,t, x)
            NFET = 2^nextpow2(length(t));
            X = fft(x, NFET)/length(t);
            results = [[1:1:NFET];abs(X)];
        end
        
        function results = powerAnalyze(~,t,x)
            n=0:length(t);
            window=boxcar(length(x));
            [Pxx,f]=periodogram(x,window,length(x),1000);
            x = 10*log10(Pxx);
            t = f;
%                 app.UIAxes.xlim([0,0.5*fs]);
%                 app.UIAxes.title('普通信号功率谱分析');
%                 app.UIAxes.xlabel('模拟频率(Hz)');
%                 app.UIAxes.ylabel('功率谱密度(dB)');
            results = [t, x];
        end


        function results = LowPass(app,fhp,fhst)
            wp=2*pi*fhp/fs;wst=2*pi*fhst/fs;
            wp=wp/pi;wst=wst/pi;
            width=wst-wp;
            if as<21
                tr=0.9;%用来做过渡带宽
            elseif as<44
                tr=3.1;
            elseif as<53
                tr=3.3;
            else
                tr=5.5;
            end
            
            N=ceil(2*pi*tr/width)+1;
            n=0:(N-1);
            wc=(wp+wst)/2;
            alpha=(N-1)/2;
            hd=(wc/pi)*sinc((wc/pi)*(n-alpha));
            if as<21
                w_fun=boxcar(N);%矩形窗
            elseif as<44
                w_fun=hanning(N); %汉宁窗             
            elseif as<53
                w_fun=hamming(N);%海明窗
            else
                w_fun=blackman(N); %布莱克曼窗                  
            end
            h=hd.*w_fun';        
    
            [Hr,w1]=zerophase(h);
            Hr=20*log10(abs(Hr));
            plot(w1/pi,Hr);%产生低通的时域波形

            X=filter(h,1,wave); 
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.pass_up.Enable = 'off';
            app.stop_up.Enable = 'off';
            app.stop_down.Enable = 'off';
            app.pass_down.Enable = 'off';
            
            app.F = 1;
            app.Lamp.Color = [0.5 0.5 0.5];
        end

        % Value changed function: SignalSelect
        function SignalSelectValueChanged(app, event)
            value = app.SignalSelect.Value;
            updatePlot(app);
        end

        % Value changing function: Period
        function PeriodValueChanging(app, event)
            changingValue = event.Value;
            app.F = changingValue;
            
            updatePlot(app);
        end

        % Value changed function: Noise
        function NoiseValueChanged(app, event)
            value = app.Noise.Value;
            updatePlot(app);
        end

        % Callback function
        function SignalAnalyzeSelectionChanged(app, event)
            selectedButton = app.SignalAnalyze.SelectedObject;
            updatePlot(app);
        end

        % Value changed function: Analyze
        function AnalyzeValueChanged(app, event)
            value = app.Analyze.Value;
            updatePlot(app);
        end

        % Value changed function: Filter
        function FilterValueChanged(app, event)
            value = app.Filter.Value;
            switch value
                case 'None'
                    app.pass_up.Enable = 'off';
                    app.stop_up.Enable = 'off';
                    app.stop_down.Enable = 'off';
                    app.pass_down.Enable = 'off';
                case 'LPF'
                    app.pass_up.Enable = 'on';
                    app.stop_up.Enable = 'on';
                    app.stop_down.Enable = 'off';
                    app.pass_down.Enable = 'off';
                case 'BPF'
                    app.pass_up.Enable = 'on';
                    app.stop_up.Enable = 'on';
                    app.stop_down.Enable = 'on';
                    app.pass_down.Enable = 'on';
                case 'HPF'
                    app.pass_up.Enable = 'off';
                    app.stop_up.Enable = 'off';
                    pp.stop_down.Enable = 'on';
                    app.pass_down.Enable = 'on';
            end
        end

        % Value changed function: Domin
        function DominValueChanged(app, event)
            value = app.Domin.Value;
            updatePlot(app);
        end

        % Value changed function: Modulation
        function ModulationValueChanged(app, event)
            value = app.Modulation.Value;
            updatePlot(app);
        end

        % Value changed function: Mul
        function MulValueChanged(app, event)
            value = app.Mul.Value;
            if(value)
                app.add.Enable = 'on';
                app.minus.Enable = 'on';
            else
                app.add.Enable = 'off';
                app.minus.Enable = 'off';
            end
        end

        % Button pushed function: add
        function addButtonPushed(app, event)
            app.xx = app.xx + app.multix;
            updatePlot(app);
        end

        % Button pushed function: minus
        function minusButtonPushed(app, event)
            app.xx = app.xx - app.multix;
            updatePlot(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.NoiseS_dBLabel = uilabel(app.UIFigure);
            app.NoiseS_dBLabel.Text = 'Noise(S_dB)';
            app.Noise = uiknob(app.UIFigure, 'discrete');
            app.Noise.Items = {'100', '50', '20', '10', '1'};
            app.Noise.ValueChangedFcn = createCallbackFcn(app, @NoiseValueChanged, true);
            app.Noise.Value = '100';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '数字信号处理大作业';
            app.PeriodKnobLabel = uilabel(app.UIFigure);
            app.PeriodKnobLabel.Text = 'Period';
            app.Period = uiknob(app.UIFigure, 'continuous');
            app.Period.Limits = [1 10];
            app.Period.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.Period.ValueChangingFcn = createCallbackFcn(app, @PeriodValueChanging, true);
            app.Period.Value = 1;
            app.DominSwitchLabel = uilabel(app.UIFigure);
            app.DominSwitchLabel.Text = 'Domin';
            app.Domin = uiswitch(app.UIFigure, 'slider');
            app.Domin.Items = {'Time', 'Freq'};
            app.Domin.ValueChangedFcn = createCallbackFcn(app, @DominValueChanged, true);
            app.Domin.Value = 'Time';
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.UIFigure);
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.add = uibutton(app.Panel, 'push');
            app.add.ButtonPushedFcn = createCallbackFcn(app, @addButtonPushed, true);
            app.add.Text = '+';
            app.minus = uibutton(app.Panel, 'push');
            app.minus.ButtonPushedFcn = createCallbackFcn(app, @minusButtonPushed, true);
            app.minus.Text = '-';
            app.AmpEditFieldLabel = uilabel(app.Panel);
            app.AmpEditFieldLabel.Text = 'Amp';
            app.Amp = uieditfield(app.Panel, 'numeric');
            app.Amp.Value = 1;
            app.SignalSelectDropDownLabel = uilabel(app.Panel);
            app.SignalSelectDropDownLabel.Text = 'Signal Select';
            app.SignalSelect = uidropdown(app.Panel);
            app.SignalSelect.Items = {'None', 'Square', 'Triangular', 'Sine'};
            app.SignalSelect.ValueChangedFcn = createCallbackFcn(app, @SignalSelectValueChanged, true);
            app.SignalSelect.Value = 'None';
            app.Modulation = uibutton(app.Panel, 'state');
            app.Modulation.ValueChangedFcn = createCallbackFcn(app, @ModulationValueChanged, true);
            app.Modulation.Text = 'Modulation';
            app.Mul = uibutton(app.Panel, 'state');
            app.Mul.ValueChangedFcn = createCallbackFcn(app, @MulValueChanged, true);
            app.Mul.Text = 'Mul';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Signal List'};
            app.UITable.RowName = {};
            app.AnalyzeLabel = uilabel(app.UIFigure);
            app.AnalyzeLabel.Text = 'Analyze';
            app.Analyze = uiknob(app.UIFigure, 'discrete');
            app.Analyze.Items = {'None', 'Power'};
            app.Analyze.ValueChangedFcn = createCallbackFcn(app, @AnalyzeValueChanged, true);
            app.Analyze.Value = 'None';
            app.pass_upSliderLabel = uilabel(app.UIFigure);
            app.pass_upSliderLabel.Text = 'pass_up';
            app.pass_up = uislider(app.UIFigure);
            app.stop_upSliderLabel = uilabel(app.UIFigure);
            app.stop_upSliderLabel.Text = 'stop_up';
            app.stop_up = uislider(app.UIFigure);
            app.stop_downSliderLabel = uilabel(app.UIFigure);
            app.stop_downSliderLabel.Text = 'stop_down';
            app.stop_down = uislider(app.UIFigure);
            app.pass_downSliderLabel = uilabel(app.UIFigure);
            app.pass_downSliderLabel.Text = 'pass_down';
            app.pass_down = uislider(app.UIFigure);
            app.FilterLabel = uilabel(app.UIFigure);
            app.FilterLabel.Text = 'Filter';
            app.Filter = uiknob(app.UIFigure, 'discrete');
            app.Filter.Items = {'None', 'LPF', 'BPF', 'HPF', 'BSF'};
            app.Filter.ValueChangedFcn = createCallbackFcn(app, @FilterValueChanged, true);
            app.Filter.Value = 'None';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SignalDisplayTab = uitab(app.TabGroup);
            app.SignalDisplayTab.Title = 'Signal Display';
            app.UIAxes = uiaxes(app.SignalDisplayTab);
            title(app.UIAxes, 'Signal Display')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.FilterDisplayTab = uitab(app.TabGroup);
            app.FilterDisplayTab.Title = 'Filter Display';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Signal_box_v3

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end